classdef USER2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CalcularButton             matlab.ui.control.Button
        BaseLabel                  matlab.ui.control.Label
        T                          matlab.ui.control.NumericEditField
        Eslabon2Label              matlab.ui.control.Label
        R                          matlab.ui.control.NumericEditField
        Eslabon3Label              matlab.ui.control.Label
        R2                         matlab.ui.control.NumericEditField
        MuecaLabel                 matlab.ui.control.Label
        T2                         matlab.ui.control.NumericEditField
        PinzasLabel                matlab.ui.control.Label
        R3                         matlab.ui.control.NumericEditField
        PosicinfinalLabel          matlab.ui.control.Label
        PosicininicialLabel        matlab.ui.control.Label
        BaseLabel_2                matlab.ui.control.Label
        T_2                        matlab.ui.control.NumericEditField
        Eslabon2Label_2            matlab.ui.control.Label
        R_2                        matlab.ui.control.NumericEditField
        Eslabon3Label_2            matlab.ui.control.Label
        R2_2                       matlab.ui.control.NumericEditField
        MuecaLabel_2               matlab.ui.control.Label
        T2_2                       matlab.ui.control.NumericEditField
        PinzasLabel_2              matlab.ui.control.Label
        R3_2                       matlab.ui.control.NumericEditField
        RestriccinTextArea_2Label  matlab.ui.control.Label
        RestriccinTextArea_2       matlab.ui.control.TextArea
        RestriccinTextAreaLabel    matlab.ui.control.Label
        RestriccinTextArea         matlab.ui.control.TextArea
        RestriccinTextArea_3Label  matlab.ui.control.Label
        RestriccinTextArea_3       matlab.ui.control.TextArea
        RestriccinTextArea_4Label  matlab.ui.control.Label
        RestriccinTextArea_4       matlab.ui.control.TextArea
        RestriccinTextArea_5Label  matlab.ui.control.Label
        RestriccinTextArea_5       matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            T=25;
%L=[40 150 155 30 0.1];

O0=app.T_2.Value;O1=app.R_2.Value;O2=app.R2_2.Value;O3=app.T2_2.Value;O4=app.R3_2.Value;
%F0=360;F1=360;F2=0;F3=0;F4=0;
F0=app.T.Value;F1=app.R.Value;F2=app.R2.Value;F3=app.T2.Value;F4=app.R3.Value;
err=0;
er2=0;
er3=0;
if F1>=90 || F1<=-200
F1=0; 
disp('Valor del angulo eslabon 2 no se pudo cumplir, se tomara 0')
er2=1;
end
if F2>=120 || F2<=-120
F2=0; 
er3=1;
disp('Valor del angulo eslabon 3 no se pudo cumplir, se tomara 0')
end
if F4>=30 || F4<=-33
F4=0; 
err=1;
end
k0=(F0-O0)/T;k1=(F1-O1)/T;k2=(F2-O2)/T;k3=(F3-O3)/T;k4=(F4-O4)/T;
for k=0:T
    pause(.2);clf;
    L=[0 80 120 50 30 40 15];

%Tabla DH
thetai=[O0 0 0 O3 0 0 0 0];%intersecciones en eje z
di=[L(1) L(2) L(3) L(4) L(5) L(6) -L(5) -L(6)]; 
%di=[0 L(1) L(2) L(3) L(4) L(5) 0]; 
ai=[0 0 0 0 0 0 0 0];%traslados en eje x
alphai=[0 90+O1 O2 0 90 -90+O4  90 -90-O4];

%Se aplica el DH
mth1=DH(thetai(1),di(1),ai(1),alphai(1));
mth2=DH(thetai(2),di(2),ai(2),alphai(2));
mth3=DH(thetai(3),di(3),ai(3),alphai(3));
mth4=DH(thetai(4),di(4),ai(4),alphai(4));
mth5=DH(thetai(5),di(5),ai(5),alphai(5));
mth6=DH(thetai(6),di(6),ai(6),alphai(6));
mth7=DH(thetai(7),di(7),ai(7),alphai(7));
mth8=DH(thetai(8),di(8),ai(8),alphai(8));
%Matrices de transformacipon
M1=mth1;
M2=mth1*mth2;
M3=mth1*mth2*mth3;
M4=mth1*mth2*mth3*mth4;
M5=mth1*mth2*mth3*mth4*mth5;
M6=mth1*mth2*mth3*mth4*mth5*mth6;
M7=mth1*mth2*mth3*mth4*mth5;
M8=mth1*mth2*mth3*mth4*mth5*mth8;

%crear arreglo de valores
MTH=struct('M1',M1,'M2',M2,'M3',M3,'M4',M4,'M5',M5,'M6',M6,'M7',M7,'M8',M8);
assignin('base','MTH',MTH);%guardar en el workspace
grid on
eslabon0(MTH.M1) 
eslabon1(MTH.M2) %Primero rotatorio
eslabon2(MTH.M3) %segundo rotatorio 
eslabon3(MTH.M4) %muñeca
eslabon4(MTH.M5) %soporte de mano
eslabon5(MTH.M6) %dedo 1
eslabon4_2(MTH.M7) %soporte de mano
eslabon5_2(MTH.M8) %dedo 1
O0=k0+O0;
O1=k1+O1;
O2=k2+O2;
O3=k3+O3;
O4=k4+O4;
   grid on
   rectangle('Position',[-100,-100,200,200],'FaceColor',[.5,0,0],'EdgeColor','c',...
    'LineWidth',3)
    rectangle('Position',[-50,-50,100,100],'Curvature',[1 1],'FaceColor',[0,0,0],'EdgeColor','m',...
    'LineWidth',3)
if err==1
    text(250,250,250,'El valor del ángulo de la pinza no se pudo cumplir, se tomara 0°')
end

if er3==1
    text(250,250,250,'El valor del ángulo del eslabon3 no se pudo cumplir, se tomara 0°')
end
  
if er2==1
    text(250,250,250,'El valor del ángulo del eslabon2 no se pudo cumplir, se tomara 0°')
end
end

  %S0=F0;S1=O1;S2=O2;S3=O3;S4=F4;
        end

        % Callback function
        function UWUValueChanged(app, event)
            u = app.UWU.Value
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.BaseLabel = uilabel(app.UIFigure);
            app.BaseLabel.Text = 'Base';
            app.T = uieditfield(app.UIFigure, 'numeric');
            app.Eslabon2Label = uilabel(app.UIFigure);
            app.Eslabon2Label.Text = 'Eslabon2';
            app.R = uieditfield(app.UIFigure, 'numeric');
            app.Eslabon3Label = uilabel(app.UIFigure);
            app.Eslabon3Label.Text = 'Eslabon3';
            app.R2 = uieditfield(app.UIFigure, 'numeric');
            app.MuecaLabel = uilabel(app.UIFigure);
            app.MuecaLabel.Text = 'Muñeca';
            app.T2 = uieditfield(app.UIFigure, 'numeric');
            app.PinzasLabel = uilabel(app.UIFigure);
            app.PinzasLabel.Text = 'Pinzas';
            app.R3 = uieditfield(app.UIFigure, 'numeric');
            app.PosicinfinalLabel = uilabel(app.UIFigure);
            app.PosicinfinalLabel.Text = 'Posición final';
            app.PosicininicialLabel = uilabel(app.UIFigure);
            app.PosicininicialLabel.Text = 'Posición inicial';
            app.BaseLabel_2 = uilabel(app.UIFigure);
            app.BaseLabel_2.Text = 'Base';
            app.T_2 = uieditfield(app.UIFigure, 'numeric');
            app.Eslabon2Label_2 = uilabel(app.UIFigure);
            app.Eslabon2Label_2.Text = 'Eslabon2';
            app.R_2 = uieditfield(app.UIFigure, 'numeric');
            app.Eslabon3Label_2 = uilabel(app.UIFigure);
            app.Eslabon3Label_2.Text = 'Eslabon3';
            app.R2_2 = uieditfield(app.UIFigure, 'numeric');
            app.MuecaLabel_2 = uilabel(app.UIFigure);
            app.MuecaLabel_2.Text = 'Muñeca';
            app.T2_2 = uieditfield(app.UIFigure, 'numeric');
            app.PinzasLabel_2 = uilabel(app.UIFigure);
            app.PinzasLabel_2.Text = 'Pinzas';
            app.R3_2 = uieditfield(app.UIFigure, 'numeric');
            app.RestriccinTextArea_2Label = uilabel(app.UIFigure);
            app.RestriccinTextArea_2Label.Text = 'Restricción: ';
            app.RestriccinTextArea_2 = uitextarea(app.UIFigure);
            app.RestriccinTextArea_2.Value = {'No tiene '; 'restricción '};
            app.RestriccinTextAreaLabel = uilabel(app.UIFigure);
            app.RestriccinTextAreaLabel.Text = 'Restricción: ';
            app.RestriccinTextArea = uitextarea(app.UIFigure);
            app.RestriccinTextArea.Value = {'Áng max: 90°'; 'Áng min:-200°'};
            app.RestriccinTextArea_3Label = uilabel(app.UIFigure);
            app.RestriccinTextArea_3Label.Text = 'Restricción: ';
            app.RestriccinTextArea_3 = uitextarea(app.UIFigure);
            app.RestriccinTextArea_3.Value = {'Áng max: 120°'; 'Áng min:-120°'};
            app.RestriccinTextArea_4Label = uilabel(app.UIFigure);
            app.RestriccinTextArea_4Label.Text = 'Restricción: ';
            app.RestriccinTextArea_4 = uitextarea(app.UIFigure);
            app.RestriccinTextArea_4.Value = {'No tiene '; 'restricción '};
            app.RestriccinTextArea_5Label = uilabel(app.UIFigure);
            app.RestriccinTextArea_5Label.Text = 'Restricción: ';
            app.RestriccinTextArea_5 = uitextarea(app.UIFigure);
            app.RestriccinTextArea_5.Value = {'Áng max: 30°'; 'Áng min:-33°'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = USER2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end